classdef EVA_H < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MassofSO2TgEditFieldLabel      matlab.ui.control.Label
        MassofSO2TgEditField           matlab.ui.control.NumericEditField
        VolcanolatitudedegNEditFieldLabel  matlab.ui.control.Label
        VolcanolatitudedegNEditField   matlab.ui.control.NumericEditField
        SO2injectionheightkmaslEditFieldLabel  matlab.ui.control.Label
        SO2injectionheightkmaslEditField  matlab.ui.control.NumericEditField
        EruptionmonthListBoxLabel      matlab.ui.control.Label
        EruptionmonthListBox           matlab.ui.control.ListBox
        SAODSO4scalingfactorTgSEditFieldLabel  matlab.ui.control.Label
        SAODSO4scalingfactorTgSEditField  matlab.ui.control.NumericEditField
        SulfateaerosolproductiontimescalemonthEditFieldLabel  matlab.ui.control.Label
        SulfateaerosolproductiontimescalemonthEditField  matlab.ui.control.NumericEditField
        SulfateaerosolhorizontalmixingtimescalemonthEditFieldLabel  matlab.ui.control.Label
        SulfateaerosolhorizontalmixingtimescalemonthEditField  matlab.ui.control.NumericEditField
        SeasonallydependentmixingButtonGroup  matlab.ui.container.ButtonGroup
        NoButton                       matlab.ui.control.RadioButton
        YesButton                      matlab.ui.control.RadioButton
        SO4losstimescalesofrecommendedvalueineachboxLabel  matlab.ui.control.Label
        SulfateaerosollosstimescalesofrecommendedvalueineachboxSlider  matlab.ui.control.Slider
        EruptionsourceparametersLabel  matlab.ui.control.Label
        ModelparametersLabel           matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        RunButton                      matlab.ui.control.Button
        TropopauseheightatvolcanolocationkmaslEditFieldLabel  matlab.ui.control.Label
        TropopauseheightatvolcanolocationkmaslEditField  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Define model parameters
            h1lim=16;
            h2lim=20;
            latlim=25;
            
            A = app.SAODSO4scalingfactorTgSEditField.Value;
            tauprod = ones(8,1)*app.SulfateaerosolproductiontimescalemonthEditField.Value;
            tauloss = [2.3;9.1;2.3;2.6;16.1;2.6;3.6;3.6]*app.SulfateaerosollosstimescalesofrecommendedvalueineachboxSlider.Value/100;
            taumixm = [app.SulfateaerosolhorizontalmixingtimescalemonthEditField.Value*ones(4,1);inf;inf];
            
            if app.NoButton.Value
                amix=zeros(6,1);
                smix=zeros(6,1);
            else
                amix=0.5*ones(6,1);
                smix=[7;1;7;1;7;1];
            end
            
            tauowmm=ones(6,1)*inf;
            aowm=zeros(6,1);% seasonal cycle amplitude for one-way mixing
            sowm=zeros(6,1);% peak mixing month for one-way mixing
            
            modelpara=[A;tauprod;tauloss;taumixm;amix;smix;tauowmm;aowm;sowm];
            backinj=[0.0021;0.0116;0.0035;0.0003;0.0001;0.0004;0.0023;0.0032];
            mstar=10;
            R_reff=0.26;
            
            so2mass=app.MassofSO2TgEditField.Value;
            erulat=app.VolcanolatitudedegNEditField.Value;
            eruheight=app.SO2injectionheightkmaslEditField.Value;
            erutropo=app.TropopauseheightatvolcanolocationkmaslEditField.Value;
            listitems=app.EruptionmonthListBox.Items;
            hbox = app.EruptionmonthListBox.Value;
            [~, erudate] = ismember(hbox, listitems)
            
            %Calculate mass injected in each box
            [inmass intime]=so2injection_8boxes(h1lim,h2lim,latlim,so2mass,eruheight,erulat,erudate,erutropo)
            
            %Define integration parameters
            
            tspan = [0.5 5*12-0.5];
            ic = [0.0126;0.0468;0.0152;0.0192;0.0359;0.0218;0.0349;0.0417];
            ode_opts = odeset('RelTol',1e-4,'AbsTol',1e-8);
            tref=(tspan(1):0.1:tspan(end))';
            time_eru=(tref-erudate+0.5)/12;
            
            %Run the model
            [t_ode,SO4mass] = ode45(@(t,y) eightboxequations(t,y,inmass*0,intime,modelpara,backinj), tspan, ic, ode_opts);%
            ic=SO4mass(end,:);
            [t_ode,SO4mass] = ode45(@(t,y) eightboxequations(t,y,inmass,intime,modelpara,backinj), tspan, ic, ode_opts);%
            SO4mass=interp1(t_ode,SO4mass,tref,'pchip');
            
            %Postprocessing
            wl_req=[0.550]';
            [gmsaod, saod, reff, ext, lat, alt]=postproc(SO4mass,modelpara,mstar,R_reff,wl_req);
            saod=squeeze(saod);
            xlim(app.UIAxes,[-0.1 max(time_eru)])
            
            %plot
            plot(app.UIAxes,time_eru,gmsaod,'k-','LineWidth',2)
            
            contourf(app.UIAxes2,time_eru,lat,saod')
            h=colorbar(app.UIAxes2)
            colormap(app.UIAxes2,flipud(hot))
            
            
            save('EVA_H_appresults.mat','saod','gmsaod','lat','time_eru')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MassofSO2TgEditFieldLabel = uilabel(app.UIFigure);
            app.MassofSO2TgEditFieldLabel.Text = 'Mass of SO2 (Tg)';
            app.MassofSO2TgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassofSO2TgEditField.Limits = [0 Inf];
            app.MassofSO2TgEditField.Value = 18;
            app.VolcanolatitudedegNEditFieldLabel = uilabel(app.UIFigure);
            app.VolcanolatitudedegNEditFieldLabel.Text = 'Volcano latitude (deg. N)';
            app.VolcanolatitudedegNEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolcanolatitudedegNEditField.Limits = [-90 90];
            app.VolcanolatitudedegNEditField.Value = 15.1;
            app.SO2injectionheightkmaslEditFieldLabel = uilabel(app.UIFigure);
            app.SO2injectionheightkmaslEditFieldLabel.Text = 'SO2 injection height (km a.s.l.)';
            app.SO2injectionheightkmaslEditField = uieditfield(app.UIFigure, 'numeric');
            app.SO2injectionheightkmaslEditField.Limits = [0 Inf];
            app.SO2injectionheightkmaslEditField.Value = 25;
            app.EruptionmonthListBoxLabel = uilabel(app.UIFigure);
            app.EruptionmonthListBoxLabel.Text = 'Eruption month';
            app.EruptionmonthListBox = uilistbox(app.UIFigure);
            app.EruptionmonthListBox.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.EruptionmonthListBox.Value = 'June';
            app.SAODSO4scalingfactorTgSEditFieldLabel = uilabel(app.UIFigure);
            app.SAODSO4scalingfactorTgSEditFieldLabel.Text = {'Sulfate aerosol - Stratospheric'; 'aerosol optical depth (SAOD) '; 'scaling factor (unit SAOD /Tg S)'};
            app.SAODSO4scalingfactorTgSEditField = uieditfield(app.UIFigure, 'numeric');
            app.SAODSO4scalingfactorTgSEditField.Limits = [0 Inf];
            app.SAODSO4scalingfactorTgSEditField.Value = 0.0187;
            app.SulfateaerosolproductiontimescalemonthEditFieldLabel = uilabel(app.UIFigure);
            app.SulfateaerosolproductiontimescalemonthEditFieldLabel.Text = {'Sulfate aerosol production'; 'timescale (month)'};
            app.SulfateaerosolproductiontimescalemonthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SulfateaerosolproductiontimescalemonthEditField.Limits = [0 Inf];
            app.SulfateaerosolproductiontimescalemonthEditField.Value = 8;
            app.SulfateaerosolhorizontalmixingtimescalemonthEditFieldLabel = uilabel(app.UIFigure);
            app.SulfateaerosolhorizontalmixingtimescalemonthEditFieldLabel.Text = {'Sulfate aerosol horizontal '; 'mixing timescale (month)'};
            app.SulfateaerosolhorizontalmixingtimescalemonthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SulfateaerosolhorizontalmixingtimescalemonthEditField.Limits = [0 Inf];
            app.SulfateaerosolhorizontalmixingtimescalemonthEditField.Value = 10.2;
            app.SeasonallydependentmixingButtonGroup = uibuttongroup(app.UIFigure);
            app.SeasonallydependentmixingButtonGroup.Title = 'Seasonally dependent mixing';
            app.NoButton = uiradiobutton(app.SeasonallydependentmixingButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.SeasonallydependentmixingButtonGroup);
            app.YesButton.Text = 'Yes';
            app.SO4losstimescalesofrecommendedvalueineachboxLabel = uilabel(app.UIFigure);
            app.SO4losstimescalesofrecommendedvalueineachboxLabel.Text = {'Sulfate aerosol loss timescales'; '(% of recommended value in each box)'};
            app.SulfateaerosollosstimescalesofrecommendedvalueineachboxSlider = uislider(app.UIFigure);
            app.SulfateaerosollosstimescalesofrecommendedvalueineachboxSlider.Limits = [10 190];
            app.SulfateaerosollosstimescalesofrecommendedvalueineachboxSlider.Value = 100;
            app.EruptionsourceparametersLabel = uilabel(app.UIFigure);
            app.EruptionsourceparametersLabel.Text = 'Eruption source parameters';
            app.ModelparametersLabel = uilabel(app.UIFigure);
            app.ModelparametersLabel.Text = 'Model parameters';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Global mean stratospheric aerosol optical depth (SAOD) @550nm')
            xlabel(app.UIAxes, 'Time (year after eruption)')
            ylabel(app.UIAxes, 'SAOD')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'SAOD @550nm')
            xlabel(app.UIAxes2, 'Time (year after eruption)')
            ylabel(app.UIAxes2, 'Latitude (deg. N)')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.TropopauseheightatvolcanolocationkmaslEditFieldLabel = uilabel(app.UIFigure);
            app.TropopauseheightatvolcanolocationkmaslEditFieldLabel.Text = {'Tropopause height at'; 'volcano location (km a.s.l.)'};
            app.TropopauseheightatvolcanolocationkmaslEditField = uieditfield(app.UIFigure, 'numeric');
            app.TropopauseheightatvolcanolocationkmaslEditField.Value = 16;
        end
    end

    methods (Access = public)

        % Construct app
        function app = EVA_H

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end